classdef AZ_Capstone_MATLAB_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        rawData                matlab.ui.control.Table
        PowermWCheckBox        matlab.ui.control.CheckBox
        TemperatureCCheckBox   matlab.ui.control.CheckBox
        CurrentmACheckBox      matlab.ui.control.CheckBox
        LoadVoltageVCheckBox   matlab.ui.control.CheckBox
        ShuntVoltageVCheckBox  matlab.ui.control.CheckBox
        BusVoltageVCheckBox    matlab.ui.control.CheckBox
        LoadFileButton         matlab.ui.control.Button
        UIAxes                 matlab.ui.control.UIAxes
    end

    properties (Access = private)
        TimeData
        BusVoltageData
        ShuntVoltageData
        LoadVoltageData
        CurrentData
        TemperatureData
        PowerData
    end

    methods (Access = private)
        
        function updatePlot(app)
        axis(app.UIAxes, 'tight');  % Auto-zoom to data

       % Clear the axes and reset both yyaxis modes
    cla(app.UIAxes);  
    yyaxis(app.UIAxes, 'left');
    ylabel(app.UIAxes, '');

    cla(app.UIAxes);  
    yyaxis(app.UIAxes, 'right');
        ylabel(app.UIAxes, '');

    cla(app.UIAxes);  

    % Reset to left for plotting voltage-related values
    yyaxis(app.UIAxes, 'left');
    hold(app.UIAxes, 'on');
    grid(app.UIAxes, 'on');
    
    % Plot each dataset if its checkbox is enabled
    if app.BusVoltageVCheckBox.Value
        plot(app.UIAxes, app.TimeData, app.BusVoltageData, 'r-', 'DisplayName', 'Bus Voltage (V)');
                       yyaxis(app.UIAxes, 'left');

        ylabel(app.UIAxes, 'Bus Voltage (V)');

    end
    
    if app.ShuntVoltageVCheckBox.Value
        plot(app.UIAxes, app.TimeData, app.ShuntVoltageData, 'g--', 'DisplayName', 'Shunt Voltage (V)');
                       yyaxis(app.UIAxes, 'left');

        ylabel(app.UIAxes, 'Shunt Voltage (V)');

    end
    
    if app.LoadVoltageVCheckBox.Value
        plot(app.UIAxes, app.TimeData, app.LoadVoltageData, 'b:', 'DisplayName', 'Load Voltage (V)');
               yyaxis(app.UIAxes, 'left');

        ylabel(app.UIAxes, 'Load Voltage (V)');

    end
    

    if app.CurrentmACheckBox.Value
        yyaxis(app.UIAxes, 'right');
        ylabel(app.UIAxes, 'Current (mA)');
        plot(app.UIAxes, app.TimeData, app.CurrentData, 'm-', 'DisplayName', 'Current (mA)');
    end


    if app.TemperatureCCheckBox.Value
        plot(app.UIAxes, app.TimeData, app.TemperatureData, 'c-', 'DisplayName', 'Temperature (°C)');
                       yyaxis(app.UIAxes, 'left');

        ylabel(app.UIAxes, 'Temperature (°C)');
    end
    
    if app.PowermWCheckBox.Value
        plot(app.UIAxes, app.TimeData, app.PowerData, 'k-', 'DisplayName', 'Power (mW)');
                       yyaxis(app.UIAxes, 'left');

        ylabel(app.UIAxes, 'Power (mW)');
    end
    
    hold(app.UIAxes, 'off');
    
    % Add legend and labels
    yyaxis(app.UIAxes, 'left');
    legend(app.UIAxes, 'show', 'Location', 'northeast');
    xlabel(app.UIAxes, 'Time');
    title(app.UIAxes, 'Measured Data');
    axis(app.UIAxes, 'tight');  % Supposed to fit axes tightly around data - did absolutely nothing - Note: Do not use Auto
        end
    end
  

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadFileButton
        function LoadFileButtonPushed(app, event)
                % Open file dialog to select Excel/CSV file
   % Open file dialog to select Excel/CSV file
            [filename, pathname] = uigetfile({'*.xlsx;*.xls;*.csv', 'Data Files'});
            if isequal(filename, 0)
                return;
            end
            
            try
                % Read data file
                fullpath = fullfile(pathname, filename);
                if endsWith(filename, '.csv')
                    opts = detectImportOptions(fullpath);
                    importedRawData = readtable(fullpath, opts);
                else
                    importedRawData = readtable(fullpath, 'Sheet', 1);
                end
                
                % Update the table display
                app.rawData.Data = importedRawData;
                app.rawData.ColumnName = importedRawData.Properties.VariableNames;
                
                % Extracting data columns - Represent CSV headers
                app.TimeData = importedRawData.Timestamp;  
                app.BusVoltageData = importedRawData.BusVoltage;
                app.ShuntVoltageData = importedRawData.ShuntVoltage;
                app.LoadVoltageData = importedRawData.LoadVoltage;
                app.CurrentData = importedRawData.Current_mA;
                app.TemperatureData = importedRawData.Temperature;
                app.PowerData = importedRawData.Power_mW;
                
                % Update plot
                app.updatePlot();
                
            catch ME
                errordlg(['Error loading file: ' ME.message], 'File Error');
            end
        

        end

        % Value changed function: BusVoltageVCheckBox, CurrentmACheckBox, 
        % ...and 4 other components
        function BusVoltageVCheckBoxValueChanged(app, event)
     app.updatePlot();  % Custom function 
                 
        end

        % Button down function: UIAxes
        function UIAxesButtonDown(app, event)
            
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Data')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @UIAxesButtonDown, true);
            app.LoadFileButton = uibutton(app.UIFigure, 'push');
            app.LoadFileButton.ButtonPushedFcn = createCallbackFcn(app, @LoadFileButtonPushed, true);
            app.LoadFileButton.Text = 'Load File';
            app.BusVoltageVCheckBox = uicheckbox(app.UIFigure);
            app.BusVoltageVCheckBox.ValueChangedFcn = createCallbackFcn(app, @BusVoltageVCheckBoxValueChanged, true);
            app.BusVoltageVCheckBox.Text = 'Bus Voltage (V)';
            app.ShuntVoltageVCheckBox = uicheckbox(app.UIFigure);
            app.ShuntVoltageVCheckBox.ValueChangedFcn = createCallbackFcn(app, @BusVoltageVCheckBoxValueChanged, true);
            app.ShuntVoltageVCheckBox.Text = 'Shunt Voltage (V)';
            app.LoadVoltageVCheckBox = uicheckbox(app.UIFigure);
            app.LoadVoltageVCheckBox.ValueChangedFcn = createCallbackFcn(app, @BusVoltageVCheckBoxValueChanged, true);
            app.LoadVoltageVCheckBox.Text = 'Load Voltage (V)';
            app.CurrentmACheckBox = uicheckbox(app.UIFigure);
            app.CurrentmACheckBox.ValueChangedFcn = createCallbackFcn(app, @BusVoltageVCheckBoxValueChanged, true);
            app.CurrentmACheckBox.Text = 'Current (mA)';
            app.TemperatureCCheckBox = uicheckbox(app.UIFigure);
            app.TemperatureCCheckBox.ValueChangedFcn = createCallbackFcn(app, @BusVoltageVCheckBoxValueChanged, true);
            app.TemperatureCCheckBox.Text = 'Temperature (C)';
            app.PowermWCheckBox = uicheckbox(app.UIFigure);
            app.PowermWCheckBox.ValueChangedFcn = createCallbackFcn(app, @BusVoltageVCheckBoxValueChanged, true);
            app.PowermWCheckBox.Text = 'Power (mW)';
            app.rawData = uitable(app.UIFigure);
            app.rawData.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.rawData.RowName = {};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = AZ_Capstone_MATLAB_App

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end